classdef Verification < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        imgSubject            matlab.ui.control.UIAxes
        imgNormalisedSubject  matlab.ui.control.UIAxes
        ControlPanel          matlab.ui.container.Panel
        btnLoadDatabase       matlab.ui.control.Button
        btnBuildDatabase      matlab.ui.control.Button
        btnReset              matlab.ui.control.Button
        btngrpLocalisation    matlab.ui.container.ButtonGroup
        HoughButton           matlab.ui.control.RadioButton
        IDOButton             matlab.ui.control.RadioButton
        btnLocaliseA          matlab.ui.control.Button
        btnLocaliseB          matlab.ui.control.Button
        TabGroup              matlab.ui.container.TabGroup
        IdentificationTab     matlab.ui.container.Tab
        btnIdentify           matlab.ui.control.Button
        btnSelectSubject      matlab.ui.control.Button
        VerificationTab       matlab.ui.container.Tab
        btnSelectSubjectA     matlab.ui.control.Button
        btnSelectSubjectB     matlab.ui.control.Button
        btnVerify             matlab.ui.control.Button
        MatchStatusLabel      matlab.ui.control.Label
        lmpMatchStatus        matlab.ui.control.Lamp
        imgMatch              matlab.ui.control.UIAxes
        imgNormalisedMatch    matlab.ui.control.UIAxes
    end

    properties (Access = private)
        
        mode = 'identification';
        
        subjectLoaded = false;
        databaseLoaded = false;
        
        subjectAID;
        subjectAImg;
        subjectALoaded = false;
        subjectANormalised;
        subjectACode;
        
        subjectBID;
        subjectBImg;
        subjectBLoaded = false;
        subjectBNormalised;
        subjectBCode;
        
    end
    
    methods (Access = private)
        
        function clearSubjectAAxes(app)
            cla(app.imgSubject);
            cla(app.imgNormalisedSubject);
            app.subjectALoaded = false;
            app.btnLocaliseA.Enable = false;
            app.btnVerify.Enable = false;
        end
        
        function clearSubjectBAxes(app)
            cla(app.imgMatch);
            cla(app.imgNormalisedMatch);
            app.subjectBLoaded = false;
            app.btnLocaliseB.Enable = false;
            app.btnVerify.Enable = false;
        end
        
        function [normalised, code] = locateAndEncode(app, subjectImg, image)
            
            if (app.HoughButton.Value)
                [pupilRow, pupilCol, pupilRad, irisRow, irisCol, irisRad] = localise(subjectImg, 'hough', 0);
            elseif (app.IDOButton.Value)
                [pupilRow, pupilCol, pupilRad, irisRow, irisCol, irisRad] = localise(subjectImg, 'ido', 0);
            end
            
            normalisedHeight = 32; normalisedWidth = 256;
            [normalised, ~] = normalise(subjectImg, pupilRow, pupilCol, pupilRad, irisRow, irisCol, ...
                irisRad, normalisedHeight, normalisedWidth, 0);
            
            code = gradientHistogram(normalised);
            if strcmp(image, 'A')
                % Weird error with UIstack so just catch it
                try
                    viscircles(app.imgSubject, [pupilCol, pupilRow], pupilRad, 'Color', 'b');
                catch % do nothing
                end
                try
                    viscircles(app.imgSubject, [irisCol, irisRow], irisRad, 'Color', 'b');
                catch % do nothing
                end
            else
                % Weird error with UIstack so just catch it
                try
                    viscircles(app.imgMatch, [pupilCol, pupilRow], pupilRad, 'Color', 'b');
                catch % do nothing
                end
                try
                    viscircles(app.imgMatch, [irisCol, irisRow], irisRad, 'Color', 'b');
                catch % do nothing
                end
            end
            % a random figure pops up
            close;
            
        end
        
    end
    

    methods (Access = private)

        % Button pushed function: btnSelectSubjectA
        function btnSelectSubjectAButtonPushed(app, event)
            % get image name
            [fileName, filePath] = uigetfile('G:\IP\Datasets\goodEyesV5\016\L\*.jpg', 'Subject Image');
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            if (fileName ~= 0)
                fullname = [filePath fileName];
                app.subjectAID = fileName(1:8);
                % read imAge
                app.subjectAImg = im2double(imread(fullname));
                
                % displAy imAge
                imshow(app.subjectAImg, 'Parent', app.imgSubject);
                title(app.imgSubject, app.subjectAID)
                
                app.subjectALoaded = true;
                app.btnLocaliseA.Enable = true;
                
                if(app.subjectBLoaded)
                    app.btnVerify.Enable = true;
                end
                
                [app.subjectANormalised, app.subjectACode] = locateAndEncode(app, app.subjectAImg, 'A');
                
                imshow(app.subjectANormalised, 'Parent', app.imgNormalisedSubject);
                
            end
        end

        % Button pushed function: btnSelectSubjectB
        function btnSelectSubjectBButtonPushed(app, event)
            % get image name
            [fileName, filePath] = uigetfile('G:\IP\Datasets\goodEyesV5\016\L\*.jpg', 'Subject Image');
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            if (fileName ~= 0)
                fullname = [filePath fileName];
                app.subjectBID = fileName(1:8);
                % read image
                app.subjectBImg = im2double(imread(fullname));
                
                % display image
                imshow(app.subjectBImg, 'Parent', app.imgMatch);
                title(app.imgMatch, app.subjectBID)
                
                app.subjectBLoaded = true;
                app.btnLocaliseB.Enable = true;
                
                if(app.subjectALoaded)
                    app.btnVerify.Enable = true;
                end
                
                [app.subjectBNormalised, app.subjectBCode] = locateAndEncode(app, app.subjectBImg, 'B');
                
                imshow(app.subjectBNormalised, 'Parent', app.imgNormalisedMatch);
                
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if (strcmp(selectedTab.Title,'Identification'))
                app.mode = 'identification';
                clearSubjectBAxes(app);
                app.lmpMatchStatus.Color = 'white';
                title(app.imgMatch, 'Match');
            elseif (strcmp(selectedTab.Title,'Verification'))
                app.mode = 'verification';
                clearSubjectBAxes(app);
                title(app.imgMatch, 'Query');
            end
            
        end

        % Button pushed function: btnVerify
        function btnVerifyButtonPushed(app, event)
            matchLevel = compareHistograms(app.subjectACode, app.subjectBCode);
            disp(matchLevel);
            if (matchLevel < 4700)
                app.lmpMatchStatus.Color = 'green';
            else
                app.lmpMatchStatus.Color = 'red';
            end
        end

        % Button pushed function: btnReset
        function btnResetButtonPushed(app, event)
            clearSubjectAAxes(app);
            clearSubjectBAxes(app);
            app.lmpMatchStatus.Color = 'white';
        end

        % Button pushed function: btnLocaliseA
        function btnLocaliseAButtonPushed(app, event)
            cla(app.imgSubject);
            cla(app.imgNormalisedSubject);
            
            app.lmpMatchStatus.Color = 'white';
                        
            imshow(app.subjectAImg, 'Parent', app.imgSubject);
            
            [app.subjectANormalised, app.subjectACode] = locateAndEncode(app, app.subjectBImg, 'A');
            
            imshow(app.subjectANormalised, 'Parent', app.imgNormalisedSubject);
        end

        % Button pushed function: btnLocaliseB
        function btnLocaliseBButtonPushed(app, event)
            cla(app.imgMatch);
            cla(app.imgNormalisedMatch);
            
            app.lmpMatchStatus.Color = 'white';
            
            imshow(app.subjectBImg, 'Parent', app.imgMatch);

            [app.subjectBNormalised, app.subjectBCode] = locateAndEncode(app, app.subjectBImg, 'B');
            
            imshow(app.subjectBNormalised, 'Parent', app.imgNormalisedMatch);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.imgSubject = uiaxes(app.UIFigure);
            title(app.imgSubject, 'Subject')
            app.imgNormalisedSubject = uiaxes(app.UIFigure);
            title(app.imgNormalisedSubject, 'Normalised')
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control';
            app.btnLoadDatabase = uibutton(app.ControlPanel, 'push');
            app.btnLoadDatabase.Text = 'Load Database';
            app.btnBuildDatabase = uibutton(app.ControlPanel, 'push');
            app.btnBuildDatabase.Text = 'Build Database';
            app.btnReset = uibutton(app.ControlPanel, 'push');
            app.btnReset.ButtonPushedFcn = createCallbackFcn(app, @btnResetButtonPushed, true);
            app.btnReset.Text = 'Reset';
            app.btngrpLocalisation = uibuttongroup(app.ControlPanel);
            app.btngrpLocalisation.Title = 'Localisation';
            app.HoughButton = uiradiobutton(app.btngrpLocalisation);
            app.HoughButton.Text = 'Hough';
            app.HoughButton.Value = true;
            app.IDOButton = uiradiobutton(app.btngrpLocalisation);
            app.IDOButton.Text = 'IDO';
            app.btnLocaliseA = uibutton(app.btngrpLocalisation, 'push');
            app.btnLocaliseA.ButtonPushedFcn = createCallbackFcn(app, @btnLocaliseAButtonPushed, true);
            app.btnLocaliseA.Enable = 'off';
            app.btnLocaliseA.Text = 'Localise A';
            app.btnLocaliseB = uibutton(app.btngrpLocalisation, 'push');
            app.btnLocaliseB.ButtonPushedFcn = createCallbackFcn(app, @btnLocaliseBButtonPushed, true);
            app.btnLocaliseB.Enable = 'off';
            app.btnLocaliseB.Text = 'Localise B';
            app.TabGroup = uitabgroup(app.ControlPanel);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.IdentificationTab = uitab(app.TabGroup);
            app.IdentificationTab.Title = 'Identification';
            app.btnIdentify = uibutton(app.IdentificationTab, 'push');
            app.btnIdentify.Enable = 'off';
            app.btnIdentify.Text = 'Identify';
            app.btnSelectSubject = uibutton(app.IdentificationTab, 'push');
            app.btnSelectSubject.Text = 'Select Subject';
            app.VerificationTab = uitab(app.TabGroup);
            app.VerificationTab.Title = 'Verification';
            app.btnSelectSubjectA = uibutton(app.VerificationTab, 'push');
            app.btnSelectSubjectA.ButtonPushedFcn = createCallbackFcn(app, @btnSelectSubjectAButtonPushed, true);
            app.btnSelectSubjectA.Text = 'Select Subject';
            app.btnSelectSubjectB = uibutton(app.VerificationTab, 'push');
            app.btnSelectSubjectB.ButtonPushedFcn = createCallbackFcn(app, @btnSelectSubjectBButtonPushed, true);
            app.btnSelectSubjectB.Text = 'Select Match';
            app.btnVerify = uibutton(app.VerificationTab, 'push');
            app.btnVerify.ButtonPushedFcn = createCallbackFcn(app, @btnVerifyButtonPushed, true);
            app.btnVerify.Enable = 'off';
            app.btnVerify.Text = 'Verify';
            app.MatchStatusLabel = uilabel(app.VerificationTab);
            app.MatchStatusLabel.Text = 'Match Status:';
            app.lmpMatchStatus = uilamp(app.VerificationTab);
            app.imgMatch = uiaxes(app.UIFigure);
            title(app.imgMatch, 'Match')
            app.imgNormalisedMatch = uiaxes(app.UIFigure);
            title(app.imgNormalisedMatch, 'Normalised')
        end
    end

    methods (Access = public)

        % Construct app
        function app = Verification

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end