classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ControlPanel        matlab.ui.container.Panel
        btnBuildDTB         matlab.ui.control.Button
        btnLoadDTB          matlab.ui.control.Button
        btnSubject          matlab.ui.control.Button
        btnEncode           matlab.ui.control.Button
        Panel               matlab.ui.container.Panel
        btnLocate           matlab.ui.control.Button
        btngrpLocalisation  matlab.ui.container.ButtonGroup
        btnHough            matlab.ui.control.RadioButton
        btnIDO              matlab.ui.control.RadioButton
        btngrpOccludision   matlab.ui.container.ButtonGroup
        btnOriginal         matlab.ui.control.RadioButton
        btnOcclude          matlab.ui.control.RadioButton
        SliderLabel         matlab.ui.control.Label
        Slider              matlab.ui.control.Slider
        btnIdentify         matlab.ui.control.Button
        btnClearConsole     matlab.ui.control.Button
        OutputPanel         matlab.ui.container.Panel
        Console             matlab.ui.control.TextArea
        imgSubject          matlab.ui.control.UIAxes
        imgMatch            matlab.ui.control.UIAxes
        imgMask             matlab.ui.control.UIAxes
        imgCode             matlab.ui.control.UIAxes
        imgNormalised       matlab.ui.control.UIAxes
    end

    properties (Access = private)
        subjectLoaded = false;
        databaseLoaded = false;
        
        img
        originalImg
        subjectID
        
        pupilY
        pupilX
        radPupil
        irisY
        irisX
        radIris
        
        normalisedIris
        occludedNoise
        eyeCode
        noiseMask
        
        database
        EYES
        
    end
    
    methods (Access = private)
        
        function clearSubjectDetailAxes(app)
            
            % Create imgMask
            app.imgMask = uiaxes(app.UIFigure);
            title(app.imgMask, 'Mask Bits')
            app.imgMask.Color = [0.9412 0.9412 0.9412];
            app.imgMask.Position = [641 350 590 100];
            
            % Create imgNormalised
            app.imgNormalised = uiaxes(app.UIFigure);
            title(app.imgNormalised, 'Normalised')
            app.imgNormalised.Color = [0.9412 0.9412 0.9412];
            app.imgNormalised.Position = [641 570 590 100];
            
            % Create imgCode
            app.imgCode = uiaxes(app.UIFigure);
            title(app.imgCode, 'Code Bits')
            app.imgCode.Color = [0.9412 0.9412 0.9412];
            app.imgCode.Position = [641 450 590 100];
            
            % Create imgMatch
            app.imgMatch = uiaxes(app.UIFigure);
            title(app.imgMatch, 'Match Image')
            app.imgMatch.Color = [0.9412 0.9412 0.9412];
            app.imgMatch.Position = [811 20 420 320];
        end
        
        function clearConsole(app)
            app.Console.Value = {'Load a Database and a subject image to start.'};
            %app.Console = uitextarea(app.OutputPanel);
            %app.Console.Editable = 'off';
            %app.Console.FontSize = 14;
            %app.Console.Position = [11 10 570 280];
        end
        
    end
    

    methods (Access = private)

        % Button pushed function: btnLocate
        function btnLocateButtonPushed(app, event)
            
            % LOCALISATION
            
            % get rid of any lines
            cla(app.imgSubject);
            imshow(app.originalImg, 'Parent', app.imgSubject);
            % Write console
            length = size(app.Console.Value, 1);
            % Do localisation
            if (app.btnIDO.Value)
                app.Console.Value{length+1,1} = 'IDO localisation...';
                [app.pupilY, app.pupilX, app.radPupil, app.irisY, app.irisX, app.radIris] = localise(app.originalImg, 'ido', 0);
                app.Console.Value{length+1,1} = 'IDO localisation... Done.';
                
            elseif (app.btnHough.Value)
                app.Console.Value{length+1,1} = 'Hough localisation...';
                
                [app.pupilY, app.pupilX, app.radPupil, app.irisY, app.irisX, app.radIris] = localise(app.originalImg, 'hough', 0);
                app.Console.Value{length+1,1} = 'Hough localisation... Done.';
                
            end
            
            % image to use
            app.img = app.originalImg;
            
            % covert to NaN if iris occlusion is needed
            if (app.btnOcclude.Value)
                [height, width] = size(app.img);
                upperOverlap = 20; lowerOverlap = 20;
                
                lowerEye = app.irisX+app.radIris-lowerOverlap;
                upperEye = app.irisX-app.radIris+upperOverlap;
                
                app.img(1:upperEye, 1:width) = NaN;
                app.img(lowerEye:height, 1:width) = NaN;
                
                % draw lines to indicate where cut off is
                
                
                line(app.imgSubject, [1 width], [upperEye upperEye]);
                line(app.imgSubject, [1 width], [lowerEye lowerEye]);
                length = size(app.Console.Value, 1);
                app.Console.Value{length+1,1} = 'Cropping for eyelid occlusion.';
            end
            
            % Weird error with UIstack so just catch it
            try
                viscircles(app.imgSubject, [app.pupilY, app.pupilX], app.radPupil, 'Color', 'b');
            catch % do nothing
            end
            try
                viscircles(app.imgSubject, [app.irisY, app.irisX], app.radIris, 'Color', 'b');
            catch % do nothing
            end
            
            length = size(app.Console.Value, 1);
            app.Console.Value{length+1,1} = 'Normalising...';
            normalisedHeight = 32; normalisedWidth = 256;
            [app.normalisedIris, app.occludedNoise] = normalise(app.img, app.pupilY, app.pupilX, app.radPupil, ...
                app.irisY, app.irisX, app.radIris, normalisedHeight, normalisedWidth, 0);
            
            imshow(imresize(app.normalisedIris,2), 'Parent', app.imgNormalised);
            app.imgNormalised.Position = [631, 486, 600, 200];
            app.Console.Value{length+1,1} = 'Normalising... Done.';
            
            app.btnEncode.Enable = 'on';
            
            
        end

        % Button pushed function: btnSubject
        function btnSubjectButtonPushed(app, event)
            
            % LOADING SUBJECT
            length = size(app.Console.Value, 1);
            app.Console.Value{length+1,1} = '';
            app.Console.Value{length+2,1} = 'Loading Subject Image.';
            
            % get image name
            [fileName, filePath] = uigetfile('*.jpg', 'Subject Image');
            if (fileName == 0)
                app.Console.Value{length+3,1} = 'Subject Loading Cancelled.';
            else
                fullname = [filePath fileName];
                app.subjectID = fileName(1:8);
                % read image
                app.originalImg = im2double(imread(fullname));
                
                % display image
                imshow(app.originalImg, 'Parent', app.imgSubject);
                
                length = size(app.Console.Value, 1);
                app.Console.Value{length+1,1} = ['Loaded Subject Image: ', fileName, '.'];
                
                app.subjectLoaded = true;
                app.btnLocate.Enable = 'on';

                clearSubjectDetailAxes(app);
            end


        end

        % Button pushed function: btnEncode
        function btnEncodeButtonPushed(app, event)
            
            % ENCODING SUBJECT
            
            length = size(app.Console.Value, 1);
            app.Console.Value{length+1,1} = 'Encoding...';
            [app.eyeCode, app.noiseMask] = wavelet2DExtract(app.normalisedIris, app.occludedNoise, 6, 0);
            app.Console.Value{length+1,1} = 'Encoding... Done.';
            
            imshow(app.eyeCode, 'Parent', app.imgCode);
            imshow(app.noiseMask, 'Parent', app.imgMask);
            
            if (app.subjectLoaded && app.databaseLoaded)
                app.btnIdentify.Enable = 'on';
            end
            
        end

        % Button pushed function: btnBuildDTB
        function btnBuildDTBButtonPushed(app, event)
            
            % BUILDING DATABASE
            length = size(app.Console.Value, 1);
            app.Console.Value{length+1,1} = 'Building a new Database...';
                      
            rootFolder = uigetdir('D:\Documents\IP\Datasets\CASIA-Iris-Interval','Select Root Folder');
            if (rootFolder == 0)
                app.Console.Value{length+2,1} = 'Database Building Cancelled.';
                return;
            end
            buildFolder = uigetdir('D:\Documents\IP\Datasets','Select Build Folder');
            if (buildFolder == 0)
                app.Console.Value{length+2,1} = 'Database Building Cancelled.';
                return;
            end
            
            app.Console.Value{length+2,1} = ['    from source: ', rootFolder];
            app.Console.Value{length+3,1} = ['    at location: ', buildFolder];
            buildDatabase(rootFolder, buildFolder);
            app.Console.Value{length+4,1} = 'Building a new Database... Done.';
            
            
        end

        % Button pushed function: btnIdentify
        function btnIdentifyButtonPushed(app, event)
            
            % IDENTIFYING SUBJECT
            
            length = size(app.Console.Value, 1);
            app.Console.Value{length+1,1} = 'Matching...';
            [minDist, minCounter] = match(app.EYES, app.subjectID, app.eyeCode, app.noiseMask);
            app.Console.Value{length+1,1} = 'Matching... Done.';
            
            matchName = app.EYES{minCounter, 1};
            
            app.Console.Value{length+2,1} = ['Subject ', app.subjectID, ' matched to ', ....
                matchName, ', HD ', num2str(minDist)];
            
            % display matched image
            
            matchImage = im2double(imread(fullfile(app.database,matchName(3:5), ...
                matchName(6), [matchName '.jpg'])));
            imshow(matchImage, 'Parent', app.imgMatch);
            
            
        end

        % Button pushed function: btnLoadDTB
        function btnLoadDTBButtonPushed(app, event)
            
            % LOADING DATABASE
            length = size(app.Console.Value, 1);
            app.Console.Value{length+1,1} = 'Loading Database...';
            
            db = uigetdir('D:\Documents\IP\Datasets','Select Database Root');
            if (db ==0)
                app.Console.Value{length+2,1} = 'Loading Database Cancelled';
                return;
            end
            
            app.database = db;
            
            app.Console.Value{length+2,1} = ['    from source: ', app.database];
            [app.EYES, numOfImages] = loadDatabase(app.database, '_6_0');
            app.Console.Value{length+3,1} = strcat('Loading Database... Done. (', num2str(numOfImages), ' images loaded)');
            
            app.databaseLoaded = true;
            if (app.subjectLoaded && app.databaseLoaded)
                app.btnIdentify.Enable = 'on';
            end
            
        end

        % Button pushed function: btnClearConsole
        function btnClearConsoleButtonPushed(app, event)
            clearConsole(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control';
            app.btnBuildDTB = uibutton(app.ControlPanel, 'push');
            app.btnBuildDTB.ButtonPushedFcn = createCallbackFcn(app, @btnBuildDTBButtonPushed, true);
            app.btnBuildDTB.Text = 'Build Database';
            app.btnLoadDTB = uibutton(app.ControlPanel, 'push');
            app.btnLoadDTB.ButtonPushedFcn = createCallbackFcn(app, @btnLoadDTBButtonPushed, true);
            app.btnLoadDTB.Text = 'Load Database';
            app.btnSubject = uibutton(app.ControlPanel, 'push');
            app.btnSubject.ButtonPushedFcn = createCallbackFcn(app, @btnSubjectButtonPushed, true);
            app.btnSubject.Text = 'Load Subject';
            app.btnEncode = uibutton(app.ControlPanel, 'push');
            app.btnEncode.ButtonPushedFcn = createCallbackFcn(app, @btnEncodeButtonPushed, true);
            app.btnEncode.Enable = 'off';
            app.btnEncode.Text = 'Encode';
            app.Panel = uipanel(app.ControlPanel);
            app.btnLocate = uibutton(app.Panel, 'push');
            app.btnLocate.ButtonPushedFcn = createCallbackFcn(app, @btnLocateButtonPushed, true);
            app.btnLocate.Enable = 'off';
            app.btnLocate.Text = 'Locate Iris';
            app.btngrpLocalisation = uibuttongroup(app.Panel);
            app.btngrpLocalisation.Title = 'Localisation Method';
            app.btnHough = uiradiobutton(app.btngrpLocalisation);
            app.btnHough.Text = 'Hough';
            app.btnHough.Value = true;
            app.btnIDO = uiradiobutton(app.btngrpLocalisation);
            app.btnIDO.Text = 'IDO';
            app.btngrpOccludision = uibuttongroup(app.Panel);
            app.btngrpOccludision.Title = 'Eyelid Occlusion';
            app.btnOriginal = uiradiobutton(app.btngrpOccludision);
            app.btnOriginal.Text = 'No Cropping';
            app.btnOriginal.Value = true;
            app.btnOcclude = uiradiobutton(app.btngrpOccludision);
            app.btnOcclude.Text = 'Crop Eyelids';
            app.SliderLabel = uilabel(app.btngrpOccludision);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.btngrpOccludision);
            app.Slider.Limits = [-30 30];
            app.btnIdentify = uibutton(app.ControlPanel, 'push');
            app.btnIdentify.ButtonPushedFcn = createCallbackFcn(app, @btnIdentifyButtonPushed, true);
            app.btnIdentify.Enable = 'off';
            app.btnIdentify.Text = 'Identify';
            app.btnClearConsole = uibutton(app.ControlPanel, 'push');
            app.btnClearConsole.ButtonPushedFcn = createCallbackFcn(app, @btnClearConsoleButtonPushed, true);
            app.btnClearConsole.Text = 'Clear Console';
            app.OutputPanel = uipanel(app.UIFigure);
            app.OutputPanel.Title = 'Output';
            app.Console = uitextarea(app.OutputPanel);
            app.Console.Editable = 'off';
            app.Console.Value = {'Load a Database and a subject image to start.'};
            app.imgSubject = uiaxes(app.UIFigure);
            title(app.imgSubject, 'Subject image')
            app.imgMatch = uiaxes(app.UIFigure);
            title(app.imgMatch, 'Match Image')
            app.imgMask = uiaxes(app.UIFigure);
            title(app.imgMask, 'Bit Mask')
            app.imgCode = uiaxes(app.UIFigure);
            title(app.imgCode, 'Bit Code')
            app.imgNormalised = uiaxes(app.UIFigure);
            title(app.imgNormalised, 'Normalised')
        end
    end

    methods (Access = public)

        % Construct app
        function app = main

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end